classdef app10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        l1SpinnerLabel           matlab.ui.control.Label
        l1_u                     matlab.ui.control.Spinner
        new_data_button          matlab.ui.control.Button
        wymiary_label            matlab.ui.control.Label
        wsp_p_label              matlab.ui.control.Label
        przejcie_label           matlab.ui.control.Label
        l2SpinnerLabel           matlab.ui.control.Label
        l2_u                     matlab.ui.control.Spinner
        l3SpinnerLabel           matlab.ui.control.Label
        l3_u                     matlab.ui.control.Spinner
        dSpinnerLabel            matlab.ui.control.Label
        d_u                      matlab.ui.control.Spinner
        eSpinnerLabel            matlab.ui.control.Label
        e_u                      matlab.ui.control.Spinner
        x1SpinnerLabel           matlab.ui.control.Label
        x1_u                     matlab.ui.control.Spinner
        y1SpinnerLabel           matlab.ui.control.Label
        y1_u                     matlab.ui.control.Spinner
        z1SpinnerLabel           matlab.ui.control.Label
        z1_u                     matlab.ui.control.Spinner
        wsp_k_label              matlab.ui.control.Label
        y2SpinnerLabel           matlab.ui.control.Label
        y2_u                     matlab.ui.control.Spinner
        x2Spinner_3Label         matlab.ui.control.Label
        x2_u                     matlab.ui.control.Spinner
        z2SpinnerLabel           matlab.ui.control.Label
        z2_u                     matlab.ui.control.Spinner
        delta2SpinnerLabel       matlab.ui.control.Label
        delta2_u                 matlab.ui.control.Spinner
        liczbakrokwSpinnerLabel  matlab.ui.control.Label
        liczba_k_u               matlab.ui.control.Spinner
        delta1SpinnerLabel       matlab.ui.control.Label
        delta1_u                 matlab.ui.control.Spinner
        fi_label                 matlab.ui.control.Label
        fi2SpinnerLabel          matlab.ui.control.Label
        fi2_r                    matlab.ui.control.Spinner
        fi1SpinnerLabel          matlab.ui.control.Label
        fi1_r                    matlab.ui.control.Spinner
        fi3SpinnerLabel          matlab.ui.control.Label
        fi3_r                    matlab.ui.control.Spinner
        draw_button              matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
        fi23Label                matlab.ui.control.Label
        fi23_r                   matlab.ui.control.Spinner
        sidev_plot               matlab.ui.control.UIAxes
        plot_3D                  matlab.ui.control.UIAxes
        topv_plot                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        S1
        S2
        S3
        C1
        C2
        C3
        S23
        C23  
        fi1
        fi2
        fi3
        fi23
        X
        Y
        Z
        l1
        l2
        l3
        d
        e
        a
        b
        liczba_k
        flaga
        tab_rot 
        k
        x
        y
        z
    end
    
    methods (Access = private)
   
        function obliczenia_glowne(app)
            app.liczba_k = app.liczba_k_u.Value;
            deltaX = (app.x2_u.Value - app.x1_u.Value)/app.liczba_k;
            deltaY = (app.y2_u.Value - app.y1_u.Value)/app.liczba_k;
            deltaZ = (app.z2_u.Value - app.z1_u.Value)/app.liczba_k;
            app.X = zeros(app.liczba_k, 6);
            app.Y = zeros(app.liczba_k, 6);
            app.Z = zeros(app.liczba_k, 6);
                            
            for i=0:(app.liczba_k-1) 
                if(app.flaga==0)
                    return
                end
                app.k = i;
                app.tab_rot = [(app.x1_u.Value + i*deltaX) (app.y1_u.Value + i*deltaY) (app.z1_u.Value + i*deltaZ)];
                obiczenia(app)
            end
        end
     
        function obiczenia(app)
            if app.flaga == 1 || app.flaga == 2
                if app.tab_rot(1)^2 + app.tab_rot(2)^2 < (app.e_u.Value)^(2) %literówka
                    blad(app)
                    return
                end
            end
            
           %S1 C1 Fi1
            app.S1 = 1/(app.tab_rot(1)^2 + app.tab_rot(2)^2)*(app.e_u.Value*app.tab_rot(1) + app.delta1_u.Value*app.tab_rot(2)*(app.tab_rot(1)^2+app.tab_rot(2)^2 - app.e_u.Value^2)^(1/2));
            if abs(app.S1)>1
                blad(app)
                return
            end
            
            app.C1 = 1/(app.tab_rot(1)^2 + app.tab_rot(2)^2)*(-app.e_u.Value*app.tab_rot(2) + app.delta1_u.Value*app.tab_rot(1)*(app.tab_rot(1)^2+app.tab_rot(2)^2 - app.e_u.Value^2)^(1/2));
            if abs(app.C1)>1
                blad(app)
                return
            end
            
            app.fi1 = obliczFi(app, app.S1, app.C1);
            
            %a, b
            app.a = -app.l1_u.Value + app.delta1_u.Value*(app.tab_rot(1)^2 + app.tab_rot(2)^2 - app.e_u.Value^2)^(1/2);
            app.b = (1/(2*app.l2_u.Value))*(app.a^2 + (app.tab_rot(3))^2 + (app.l2_u.Value)^2 - (app.l3_u.Value)^2);
            if (app.a^2 + app.tab_rot(2)^2 - app.b^2) < 0
                blad(app)
                return
            end
            
            %S2 C2 Fi2
            app.S2 = 1/(app.a^2 + app.tab_rot(3)^2)*(app.b*app.tab_rot(3)+app.delta2_u.Value*app.a*((app.a)^2 + (app.tab_rot(3))^2 - (app.b)^2)^(1/2));
            if abs(app.S2)>1
                blad(app)
                return
            end
            
            app.C2 = 1/(app.a^2 + app.tab_rot(3)^2)*(app.a*app.b - app.tab_rot(3)*app.delta2_u.Value*((app.a)^2 + (app.tab_rot(3))^2 - (app.b)^2)^(1/2));
            if abs(app.C2)>1
                blad(app)
                return
            end
            
            app.fi2 = obliczFi(app, app.S2, app.C2);
           %S3 C3 Fi3
           app.S3 = -(app.delta2_u.Value/app.l3_u.Value)*((app.a)^2+ (app.tab_rot(3))^2 - (app.b)^2)^(1/2);
           if abs(app.S3) > 1
               blad(app)
               return
           end
           
           app.C3 = 1/(2*app.l3_u.Value*app.l2_u.Value)*((app.a)^2 + (app.tab_rot(3))^2 - (app.l3_u.Value)^2 - (app.l2_u.Value)^2);
           if abs(app.C3) > 1
               blad(app)
               return
           end
           
           app.fi3 = obliczFi(app, app.S3, app.C3);
           
            %S23 C23 Fi23
           app.S23 = 1/app.l3_u.Value*(app.tab_rot(3) - app.l2_u.Value/((app.a)^2 + app.tab_rot(3)^2)*(app.tab_rot(3)*(app.b) + app.delta2_u.Value*(app.a)*((app.a)^2 + (app.tab_rot(3))^2 - (app.b)^2)^(1/2)));
            if abs(app.S23) > 1
                blad(app)
                return
            end
            
            app.C23 = 1/app.l3_u.Value*(app.a - app.l2_u.Value/(app.a^2 + app.tab_rot(3)^2)*(app.a*app.b - app.tab_rot(3)*app.delta2_u.Value*((app.a)^2 + (app.tab_rot(3))^2 - (app.b)^2)^(1/2)));
            if abs(app.C23) > 1
                blad(app)
                return
            end
            
            
            app.fi23 = obliczFi(app, app.S23, app.C23);
            pokazFi(app)
            przypisz_nowe(app)
            
            if app.flaga ==1
                wykres_1(app);
                pause(0.4);
            end
            
            if app.flaga ==2
                wykres_2(app);
                pause(0.4);
            end
            
        end
        
        function Fi = obliczFi(app,S,C)
            if(abs(S)<abs(C))
                Fi  = asin(S)*180/pi;
            else
                Fi = acos(C)*180/pi;
            end
        end
        
        function pokazFi(app)
            app.fi1_r.Value = app.fi1;
            app.fi1_r.Enable = 'on';
            app.fi2_r.Value = app.fi2;
            app.fi2_r.Enable = 'on';
            app.fi3_r.Value = app.fi3;
            app.fi3_r.Enable = 'on';
            app.fi23_r.Value = app.fi23;
            app.fi23_r.Enable = 'on';
        end

        
        function przypisz_nowe(app)
            %do zmiany
            app.l1 = app.l1_u.Value; 
            app.l2 = app.l2_u.Value;
            app.l3 = app.l3_u.Value;
            app.e = app.e_u.Value;
            app.d = app.d_u.Value;
            
            macierz = zeros(3,6);
            macierz(:,2) = (app.l1*[app.C1;app.S1;0]);
            macierz(:,3) = (macierz(:,2) + (app.d.*[app.S1; -app.C1; 0]));
            macierz(:,4) = (macierz(:,3) + (app.l2.*[app.C1*app.C2; app.S1*app.C2; app.S2]));
            macierz(:,5) = (macierz(:,4) + ((app.d-app.e).*[-app.S1; app.C1; 0]));
            macierz(:,6) = (macierz(:,5) + (app.l3.*[app.C23*app.C1; app.C23*app.S1; app.S23]));
            
            app.X = macierz(1,:);
            app.Y = macierz(2,:);
            app.Z = macierz(3,:);
        end
        
        function blad(app)
            uialert(uifigure,'Wprowadzone dane nie zapewniają bezpiecznego przejscia, wprowadz ponownie dane','Błędne dane','Icon','error');
            cla(app.topv_plot);
            cla(app.sidev_plot);
            cla(app.plot_3D);
            app.flaga = 0;
        end
        
        function wykres_1(app)
            plot(app.topv_plot, app.X, app.Z)
            grid (app.topv_plot, 'on');
            hold (app.topv_plot,'on');
            plot(app.sidev_plot, app.X, app.Y)
            grid (app.sidev_plot, 'on');
            hold (app.sidev_plot,'on');   
        end
      
        function wykres_2(app)
            plot3(app.plot_3D, app.X, app.Y, app.Z);
            grid (app.plot_3D, 'on');
            hold (app.plot_3D,'on');
        end 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: draw_button
        function draw_buttonPushed(app, event)
            cla(app.plot_3D);
            app.plot_3D.Visible = 'on';
            app.flaga = 2;
            obliczenia_glowne(app)
        end

        % Button pushed function: new_data_button
        function new_data_buttonPushed(app, event)
            cla(app.topv_plot);
            cla(app.sidev_plot);
            app.flaga=1;
            obliczenia_glowne(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.l1SpinnerLabel = uilabel(app.UIFigure);
            app.l1SpinnerLabel.Text = 'l1';
            app.l1_u = uispinner(app.UIFigure);
            app.l1_u.Value = 30;
            app.new_data_button = uibutton(app.UIFigure, 'push');
            app.new_data_button.ButtonPushedFcn = createCallbackFcn(app, @new_data_buttonPushed, true);
            app.new_data_button.Text = 'Wykres 2D';
            app.wymiary_label = uilabel(app.UIFigure);
            app.wymiary_label.Text = 'WYMIARY';
            app.wsp_p_label = uilabel(app.UIFigure);
            app.wsp_p_label.Text = 'WSPÓŁRZĘDNE POCZĄTKOWE';
            app.przejcie_label = uilabel(app.UIFigure);
            app.przejcie_label.Text = 'PRZEJŚCIE';
            app.l2SpinnerLabel = uilabel(app.UIFigure);
            app.l2SpinnerLabel.Text = 'l2';
            app.l2_u = uispinner(app.UIFigure);
            app.l2_u.Value = 80;
            app.l3SpinnerLabel = uilabel(app.UIFigure);
            app.l3SpinnerLabel.Text = 'l3';
            app.l3_u = uispinner(app.UIFigure);
            app.l3_u.Value = 90;
            app.dSpinnerLabel = uilabel(app.UIFigure);
            app.dSpinnerLabel.Text = 'd';
            app.d_u = uispinner(app.UIFigure);
            app.d_u.Value = 15;
            app.eSpinnerLabel = uilabel(app.UIFigure);
            app.eSpinnerLabel.Text = 'e';
            app.e_u = uispinner(app.UIFigure);
            app.e_u.Value = 15;
            app.x1SpinnerLabel = uilabel(app.UIFigure);
            app.x1SpinnerLabel.Text = 'x1';
            app.x1_u = uispinner(app.UIFigure);
            app.x1_u.Value = 70;
            app.y1SpinnerLabel = uilabel(app.UIFigure);
            app.y1SpinnerLabel.Text = 'y1';
            app.y1_u = uispinner(app.UIFigure);
            app.y1_u.Value = 40;
            app.z1SpinnerLabel = uilabel(app.UIFigure);
            app.z1SpinnerLabel.Text = 'z1';
            app.z1_u = uispinner(app.UIFigure);
            app.z1_u.Value = 50;
            app.wsp_k_label = uilabel(app.UIFigure);
            app.wsp_k_label.Text = 'WSPÓŁRZĘDNE KOŃCOWE';
            app.y2SpinnerLabel = uilabel(app.UIFigure);
            app.y2SpinnerLabel.Text = 'y2';
            app.y2_u = uispinner(app.UIFigure);
            app.y2_u.Value = 80;
            app.x2Spinner_3Label = uilabel(app.UIFigure);
            app.x2Spinner_3Label.Text = 'x2';
            app.x2_u = uispinner(app.UIFigure);
            app.x2_u.Value = 40;
            app.z2SpinnerLabel = uilabel(app.UIFigure);
            app.z2SpinnerLabel.Text = 'z2';
            app.z2_u = uispinner(app.UIFigure);
            app.z2_u.Value = 10;
            app.delta2SpinnerLabel = uilabel(app.UIFigure);
            app.delta2SpinnerLabel.Text = 'delta2';
            app.delta2_u = uispinner(app.UIFigure);
            app.delta2_u.Step = 2;
            app.delta2_u.Limits = [-1 1];
            app.delta2_u.Value = -1;
            app.liczbakrokwSpinnerLabel = uilabel(app.UIFigure);
            app.liczbakrokwSpinnerLabel.Text = 'liczba kroków';
            app.liczba_k_u = uispinner(app.UIFigure);
            app.liczba_k_u.Value = 10;
            app.delta1SpinnerLabel = uilabel(app.UIFigure);
            app.delta1SpinnerLabel.Text = 'delta1';
            app.delta1_u = uispinner(app.UIFigure);
            app.delta1_u.Step = 2;
            app.delta1_u.Limits = [-1 1];
            app.delta1_u.Value = 1;
            app.fi_label = uilabel(app.UIFigure);
            app.fi_label.Text = 'WARTOŚCI WYNIKOWYCH KĄTÓW';
            app.fi2SpinnerLabel = uilabel(app.UIFigure);
            app.fi2SpinnerLabel.Text = 'fi2';
            app.fi2_r = uispinner(app.UIFigure);
            app.fi2_r.Editable = 'off';
            app.fi2_r.Enable = 'off';
            app.fi1SpinnerLabel = uilabel(app.UIFigure);
            app.fi1SpinnerLabel.Text = 'fi1';
            app.fi1_r = uispinner(app.UIFigure);
            app.fi1_r.Editable = 'off';
            app.fi1_r.Enable = 'off';
            app.fi3SpinnerLabel = uilabel(app.UIFigure);
            app.fi3SpinnerLabel.Text = 'fi3';
            app.fi3_r = uispinner(app.UIFigure);
            app.fi3_r.Editable = 'off';
            app.fi3_r.Enable = 'off';
            app.draw_button = uibutton(app.UIFigure, 'push');
            app.draw_button.ButtonPushedFcn = createCallbackFcn(app, @draw_buttonPushed, true);
            app.draw_button.Text = 'Wykres 3D';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'WIZUALIZACJA RUCHU PUNKTU TCP '; 'MECHANIZMU O STRUKTURZE {CR, BR1, BR2} '};
            app.fi23Label = uilabel(app.UIFigure);
            app.fi23Label.Text = 'fi23';
            app.fi23_r = uispinner(app.UIFigure);
            app.fi23_r.Editable = 'off';
            app.fi23_r.Enable = 'off';
            app.sidev_plot = uiaxes(app.UIFigure);
            title(app.sidev_plot, 'Rzut z boku')
            xlabel(app.sidev_plot, 'X')
            ylabel(app.sidev_plot, 'Y')
            app.plot_3D = uiaxes(app.UIFigure);
            title(app.plot_3D, '3D')
            xlabel(app.plot_3D, 'X')
            ylabel(app.plot_3D, 'Y')
            app.topv_plot = uiaxes(app.UIFigure);
            title(app.topv_plot, 'Rzut z góry')
            xlabel(app.topv_plot, 'X')
            ylabel(app.topv_plot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end